classdef sealevels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TimeDropDownLabel    matlab.ui.control.Label
        TimeDropDown         matlab.ui.control.DropDown
        UIAxes               matlab.ui.control.UIAxes
        AllButton            matlab.ui.control.StateButton
        ArcticTempButton     matlab.ui.control.StateButton
        SeaLevelButton       matlab.ui.control.StateButton
        CO2LevelButton       matlab.ui.control.StateButton
        GlacierVolumeButton  matlab.ui.control.StateButton
        LOADDATAButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function SalinityButtonValueChanged(app, event)
          
        end

        % Value changed function: CO2LevelButton
        function CO2LevelButtonValueChanged(app, event)
            app.GlacierVolumeButton.Value = 0;
            app.SeaLevelButton.Value = 0;
            app.AllButton.Value = 0;
            app.ArcticTempButton.Value = 0;
            value = app.CO2LevelButton.Value;
            load whyphy.dat
            x = whyphy(:,1);
            y = whyphy(:,2);
            if value == 1
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 1985:5:2020;
                app.UIAxes.XTickLabel = 1985:5:2020;
                app.UIAxes.XLabel.String = 'Year';
                app.UIAxes.YTick = 350:10:420;
                app.UIAxes.YTickLabel = 350:10:420;
                app.UIAxes.YLabel.String = 'Carbon Dioxide Concentration (ppm)';
            elseif value == 0
                x = 0;
                y = 0;
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 0;
                app.UIAxes.XTickLabel = 0;
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YTick = 0;
                app.UIAxes.YTickLabel = 0;
                app.UIAxes.YLabel.String = '';
            end
        end

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            nhtempdata();
            co2();
            sealeveldata();
            glacierdata();
        end

        % Value changed function: SeaLevelButton
        function SeaLevelButtonValueChanged(app, event)
            app.GlacierVolumeButton.Value = 0;
            app.CO2LevelButton.Value = 0;
            app.AllButton.Value = 0;
            app.ArcticTempButton.Value = 0;
            value = app.SeaLevelButton.Value;
            load mynamejeff.dat
            x = mynamejeff(:,1);
            y = mynamejeff(:,2);
            if value == 1
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 1990:5:2020;
                app.UIAxes.XTickLabel = 1990:5:2020;
                app.UIAxes.XLabel.String = 'Year';
                app.UIAxes.YTick = -10:10:90;
                app.UIAxes.YTickLabel = -10:10:90;
                app.UIAxes.YLabel.String = 'Sea Level (inches)';
            elseif value == 0
                x = 0;
                y = 0;
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 0;
                app.UIAxes.XTickLabel = 0;
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YTick = 0;
                app.UIAxes.YTickLabel = 0;
                app.UIAxes.YLabel.String = '';
            end
        end

        % Value changed function: ArcticTempButton
        function ArcticTempButtonValueChanged(app, event)
            app.GlacierVolumeButton.Value = 0;
            app.CO2LevelButton.Value = 0;
            app.AllButton.Value = 0;
            app.SeaLevelButton.Value = 0;
            value = app.ArcticTempButton.Value;
            load somethingcool.dat
            x = somethingcool(:,1);
            y = somethingcool(:,2);
            if value == 1
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 1840:10:2020;
                app.UIAxes.XTickLabel = 1840:10:2020;
                app.UIAxes.XLabel.String = 'Year';
                app.UIAxes.YTick = -1:.1:1.5;
                app.UIAxes.YTickLabel = -1:.1:1.5;
                app.UIAxes.YLabel.String = 'Temperature (degrees Celsius)';
            elseif value == 0
                x = 0;
                y = 0;
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 0;
                app.UIAxes.XTickLabel = 0;
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YTick = 0;
                app.UIAxes.YTickLabel = 0;
                app.UIAxes.YLabel.String = '';
            end
        end

        % Value changed function: GlacierVolumeButton
        function GlacierVolumeButtonValueChanged(app, event)
            app.CO2LevelButton.Value = 0;
            app.SeaLevelButton.Value = 0;
            app.AllButton.Value = 0;
            app.ArcticTempButton.Value = 0;
            value = app.GlacierVolumeButton.Value;
            load meatqute.dat
            x = meatqute(:,1);
            y = meatqute(:,2);
            if value == 1
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 2000:2:2020;
                app.UIAxes.XTickLabel = 2000:2:2020;
                app.UIAxes.XLabel.String = 'Year';
                app.UIAxes.YTick = -2000:200:400;
                app.UIAxes.YTickLabel = -2000:200:400;
                app.UIAxes.YLabel.String = 'Antarctic Mass (Gigatonnes)';
            elseif value == 0
                x = 0;
                y = 0;
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 0;
                app.UIAxes.XTickLabel = 0;
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YTick = 0;
                app.UIAxes.YTickLabel = 0;
                app.UIAxes.YLabel.String = '';
            end
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            value = app.TimeDropDown.Value;
            
        end

        % Value changed function: AllButton
        function AllButtonValueChanged(app, event)
            app.CO2LevelButton.Value = 0;
            app.SeaLevelButton.Value = 0;
            app.GlacierVolumeButton.Value = 0;
            app.ArcticTempButton.Value = 0;
            value = app.AllButton.Value;
            x = 0:10:200;
            load mynamejeff.dat
            y1 = mynamejeff(:,2);
            ysea = log(y1);
            load whyphy.dat
            y2 = whyphy(:,2);
            yco2 = log(y2);
            load somethingcool.dat
            y3 = somethingcool(:,2);
            ytemp = log(y3);
            load meatqute.dat
            y4 = meatqute(:,2);
            ymass = log(y4);
            if value == 1
                plot(app.UIAxes,x,ysea);
                hold on
                plot(app.UIAxes,x,yco2);
                hold on
                plot(app.UIAxes,x,ytemp);
                hold on
                plot(app.UIAxes,x,ymass);
                %app.UIAxes.XTick = 2000:2:2020;
                %app.UIAxes.XTickLabel = 2000:2:2020;
                app.UIAxes.XLabel.String = 'Year';
                %app.UIAxes.YTick = -2000:200:400;
                %app.UIAxes.YTickLabel
                app.UIAxes.YLabel.String = 'Natural Log Correlations';
            elseif value == 0
                x = 0;
                y = 0;
                plot(app.UIAxes,x,y);
                app.UIAxes.XTick = 0;
                app.UIAxes.XTickLabel = 0;
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YTick = 0;
                app.UIAxes.YTickLabel = 0;
                app.UIAxes.YLabel.String = '';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeDropDownLabel = uilabel(app.UIFigure);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.UIFigure);
            app.TimeDropDown.Items = {'Past', 'Future'};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = 'Past';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Years')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.AllButton = uibutton(app.UIFigure, 'state');
            app.AllButton.ValueChangedFcn = createCallbackFcn(app, @AllButtonValueChanged, true);
            app.AllButton.Text = 'All';
            app.ArcticTempButton = uibutton(app.UIFigure, 'state');
            app.ArcticTempButton.ValueChangedFcn = createCallbackFcn(app, @ArcticTempButtonValueChanged, true);
            app.ArcticTempButton.Text = 'Arctic Temp';
            app.SeaLevelButton = uibutton(app.UIFigure, 'state');
            app.SeaLevelButton.ValueChangedFcn = createCallbackFcn(app, @SeaLevelButtonValueChanged, true);
            app.SeaLevelButton.Text = 'Sea Level';
            app.CO2LevelButton = uibutton(app.UIFigure, 'state');
            app.CO2LevelButton.ValueChangedFcn = createCallbackFcn(app, @CO2LevelButtonValueChanged, true);
            app.CO2LevelButton.Text = 'CO2 Level';
            app.GlacierVolumeButton = uibutton(app.UIFigure, 'state');
            app.GlacierVolumeButton.ValueChangedFcn = createCallbackFcn(app, @GlacierVolumeButtonValueChanged, true);
            app.GlacierVolumeButton.Text = 'Glacier Volume';
            app.LOADDATAButton = uibutton(app.UIFigure, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Text = 'LOAD DATA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sealevels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end